classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RecalculateButton      matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
        EpsilonEditField       matlab.ui.control.EditField
        EpsilonEditFieldLabel  matlab.ui.control.Label
        ymaxEditField          matlab.ui.control.NumericEditField
        ymaxEditFieldLabel     matlab.ui.control.Label
        yminEditField          matlab.ui.control.NumericEditField
        yminEditFieldLabel     matlab.ui.control.Label
        xmaxEditField          matlab.ui.control.NumericEditField
        xmaxEditFieldLabel     matlab.ui.control.Label
        xminEditField          matlab.ui.control.NumericEditField
        xminEditFieldLabel     matlab.ui.control.Label
        selectareaButton       matlab.ui.control.Button
        performancetestButton  matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        NEditField             matlab.ui.control.NumericEditField
        NEditFieldLabel        matlab.ui.control.Label
        matriceListBox         matlab.ui.control.ListBox
        matriceListBoxLabel    matlab.ui.control.Label
        StartButton            matlab.ui.control.StateButton
        AlgorithmButtonGroup   matlab.ui.container.ButtonGroup
        Button2                matlab.ui.control.RadioButton
        gridButton             matlab.ui.control.RadioButton
        threadsSpinner         matlab.ui.control.Spinner
        threadsSpinnerLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        epsilon = 20; % Description
        generate_matrix = @(n) toeplitz_matrix(n); %fonction pour générer la matrice 
        %taille de la trice (initiliasé par déaut à 20). 
        N = 20;
        %matrice A
        A;
        %rectangle dessiné.
        roi;
        thread = 1;
        max_tread = feature('numcores');
    end
    
    methods (Access = private)
        %modifie les valeurs de xmin, xmax, ymin, ymax en temps réelle. 
        function allevents(app, ~, evt)
            position = evt.CurrentPosition;
            disp(position);
            app.xminEditField.Value = position(1);
            app.yminEditField.Value = position(2);
            app.xmaxEditField.Value = position(1) + position(3);
            app.ymaxEditField.Value = position(2) + position(4);
        end

        function deleteRoi(app,~,~)
            app.quitting_for_select_area();
        end

        function configuration_for_select_area(app)
            app.performancetestButton.Visible = 'off';
            app.LoadButton.Visible = 'off';
            app.NEditField.Visible = 'off';
            app.NEditFieldLabel.Visible = 'off';
            app.EpsilonEditField.Visible = 'off';
            app.EpsilonEditFieldLabel.Visible = 'off';
            app.matriceListBox.Visible ='off';
            app.matriceListBoxLabel.Visible = 'off';
            app.AlgorithmButtonGroup.Visible = 'off';
            app.Button2.Visible = 'off';
            app.gridButton.Visible = 'off';
            app.threadsSpinner.Visible = 'off';
            app.threadsSpinnerLabel.Visible = 'off';
            app.selectareaButton.Enable = 'off';
            app.xmaxEditField.Visible = 'on';
            app.xminEditField.Visible = 'on';
            app.xmaxEditFieldLabel.Visible = 'on';
            app.xminEditFieldLabel.Visible = 'on';
            app.ymaxEditField.Visible = 'on';
            app.yminEditField.Visible = 'on';
            app.ymaxEditFieldLabel.Visible = 'on';
            app.yminEditFieldLabel.Visible = 'on';
            app.xminEditFieldLabel.Enable = 'on';
            app.xmaxEditFieldLabel.Enable = 'on';
            app.yminEditFieldLabel.Enable = 'on';
            app.ymaxEditFieldLabel.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            app.RecalculateButton.Visible = 'on';
            app.RecalculateButton.Enable = 'on';
            app.CancelButton.Enable = 'on';
            app.CancelButton.Visible = 'on';
        end

        function quitting_for_select_area(app)
            app.performancetestButton.Visible = 'on';
            app.LoadButton.Visible = 'off';
            app.NEditField.Visible = 'on';
            app.NEditFieldLabel.Visible = 'on';
            app.EpsilonEditField.Visible = 'on';
            app.EpsilonEditFieldLabel.Visible = 'on';
            app.matriceListBox.Visible ='on';
            app.matriceListBoxLabel.Visible = 'on';
            app.AlgorithmButtonGroup.Visible = 'on';
            app.Button2.Visible = 'on';
            app.gridButton.Visible = 'on';
            app.threadsSpinner.Visible = 'on';
            app.threadsSpinnerLabel.Visible = 'on';
            app.selectareaButton.Enable = 'on';
            app.xmaxEditField.Visible = 'off';
            app.xminEditField.Visible = 'off';
            app.xmaxEditFieldLabel.Visible = 'off';
            app.xminEditFieldLabel.Visible = 'off';
            app.ymaxEditField.Visible = 'off';
            app.yminEditField.Visible = 'off';
            app.ymaxEditFieldLabel.Visible = 'off';
            app.yminEditFieldLabel.Visible = 'off';
            app.xminEditFieldLabel.Enable = 'off';
            app.xmaxEditFieldLabel.Enable = 'off';
            app.yminEditFieldLabel.Enable = 'off';
            app.ymaxEditFieldLabel.Enable = 'off';
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            app.RecalculateButton.Visible = 'off';
            app.RecalculateButton.Enable = 'off';
            app.CancelButton.Enable = 'off';
            app.CancelButton.Visible = 'off';
        end
        

        %function that shoud execute command at the start of the GUI. 
        function OpeningFcn(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            %si la matrice est vide ou que la matrice n'est pas une matrice
            %load by l'utilisateur, la matrice est généré en utilisant la
            %fonction stoqué dans la variable generate matrix. 
            app.selectareaButton.Visible = "off";
            app.StartButton.Visible = 'off';
            if app.matriceListBox.Value ~= "load" || isempty(app.A)
                app.A = app.generate_matrix(app.N);
            end
            %lorsque nous aurons fait le second algo, gridPseud... sera
            %aussi stoqué dans une variable et choisi en fonction du bouton
            %sélectionné.
            disableDefaultInteractivity(app.UIAxes);
            [X,Y,sigmin] = gridPseudospectrum_par(app.A,app.epsilon,app.thread);  
            contourf(app.UIAxes,X, Y, log10(sigmin),(app.epsilon));
            colorbar(app.UIAxes,'eastoutside','FontName', "\epsilon");
            app.selectareaButton.Visible = "on";
            app.StartButton.Visible = 'on';
        end

        % Value changed function: matriceListBox
        function matriceListBoxValueChanged(app, event)
            value = app.matriceListBox.Value;
            %La matrice est choisie par l'utilisateur. Lorsque celle-ci
            %est changé, il est fournit la fonction de génération de la
            %matrice dans la variable generate matrix.
            switch value
                case "toeplitx"
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
                    app.generate_matrix =  @(n) toeplitz_matrix(n);
                case "chebspec"
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
                    app.generate_matrix = @(n) chebspec(n);
                case "random"
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
                    app.generate_matrix = @(n) random(n);
                case "load"
                    app.NEditField.Visible = 'off';
                    app.NEditFieldLabel.Visible = 'off';
                    app.LoadButton.Visible = 'on';
                otherwise
                    app.generate_matrix =  @(n) toeplitz_matrix(n);
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
            end
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
           app. N = app.NEditField.Value;
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile( ...
                                             {'*.mat;',  'All Files (*.*)'}, 'Pick a File to Import');
            disp(filename);
            disp(pathname);
            %vérifie qu'il y a bien un fichier fourni par l'utilisateur.
            if filename ~=0 && pathname ~= 0
                full_filename = fullfile(pathname, filename);
                app.A = importdata(full_filename);
            end
        end

        % Button pushed function: selectareaButton
        function selectareaButtonPushed(app, event)
            app.roi = drawrectangle(app.UIAxes);
            addlistener(app.roi,'MovingROI',@app.allevents);
            addlistener(app.roi,'DeletingROI',@app.deleteRoi);
            app.configuration_for_select_area()
            % donne xmin, ymin, distance par rapport à xmin, distance par
            % rapport à ymin
            position = app.roi.Position;
            disp(position(2));
            app.xminEditField.Value = position(1);
            app.yminEditField.Value = position(2);
            app.xmaxEditField.Value = position(1) + position(3);
            app.ymaxEditField.Value = position(2) + position(4);
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            value = app.EpsilonEditField.Value;
            
            %sépart les valeurs et enlève les virgules 
            app.epsilon = strsplit(value,',');
            %les convertis en double
            app.epsilon = str2double(app.epsilon);
            if size(app.epsilon) == [1 1]
                app.epsilon(2) = app.epsilon(1);
            end
            disp(app.epsilon);
            %s = handles.edit1.String; 
           
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.quitting_for_select_area();
            delete(app.roi);
        end

        % Button pushed function: RecalculateButton
        function RecalculateButtonPushed(app, event)
            delete(app.roi);
            app.quitting_for_select_area();
            app.selectareaButton.Visible = "off";
            app.RecalculateButton.Enable = 'off';
            app.RecalculateButton.Visible = 'off';
            %lorsque nous aurons fait le second algo, gridPseud... sera
            %aussi stoqué dans une variable et choisi en fonction du bouton
            %sélectionné.
            [X,Y,sigmin] = gridPseudospectrum_par_r(app.A,app.threadsSpinner.Value, app.xminEditField.Value, app.xmaxEditField.Value, app.yminEditField.Value, app.ymaxEditField.Value);   
            contourf(app.UIAxes,X, Y, log10(sigmin), app.epsilon);
            colorbar(app.UIAxes,'eastoutside','FontName', "\epsilon");
            app.selectareaButton.Visible = "on";
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: performancetestButton
        function performancetestButtonPushed(app, event)
            
        end

        % Value changed function: threadsSpinner
        function threadsSpinnerValueChanged(app, event)
            app.thread = app.threadsSpinner.Value;
            
            if app.thread > app.max_tread
                app.threadsSpinner.Value = app.max_tread;
                app.thread = app.max_tread;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Log10 of the smallest singular value of (\lambdaI - A)')
            xlabel(app.UIAxes, 'Real part (\lambda)')
            ylabel(app.UIAxes, 'Imaginary part (\lambda)')
            zlabel(app.UIAxes, 'Z')
            app.threadsSpinnerLabel = uilabel(app.UIFigure);
            app.threadsSpinnerLabel.Text = 'threads';
            app.threadsSpinner = uispinner(app.UIFigure);
            app.threadsSpinner.Limits = [1 Inf];
            app.threadsSpinner.ValueChangedFcn = createCallbackFcn(app, @threadsSpinnerValueChanged, true);
            app.threadsSpinner.Value = 1;
            app.AlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.gridButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.gridButton.Text = 'grid';
            app.gridButton.Value = true;
            app.Button2 = uiradiobutton(app.AlgorithmButtonGroup);
            app.Button2.Text = 'Button2';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.matriceListBoxLabel = uilabel(app.UIFigure);
            app.matriceListBoxLabel.Text = 'matrice';
            app.matriceListBox = uilistbox(app.UIFigure);
            app.matriceListBox.Items = {'toeplitz', 'chebspec', 'random', 'load'};
            app.matriceListBox.ValueChangedFcn = createCallbackFcn(app, @matriceListBoxValueChanged, true);
            app.matriceListBox.Value = 'toeplitz';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 20;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Visible = 'off';
            app.LoadButton.Text = 'Load';
            app.performancetestButton = uibutton(app.UIFigure, 'push');
            app.performancetestButton.ButtonPushedFcn = createCallbackFcn(app, @performancetestButtonPushed, true);
            app.performancetestButton.Text = 'performance test ';
            app.selectareaButton = uibutton(app.UIFigure, 'push');
            app.selectareaButton.ButtonPushedFcn = createCallbackFcn(app, @selectareaButtonPushed, true);
            app.selectareaButton.Visible = 'off';
            app.selectareaButton.Text = 'select area';
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Enable = 'off';
            app.xminEditFieldLabel.Visible = 'off';
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.Editable = 'off';
            app.xminEditField.Enable = 'off';
            app.xminEditField.Visible = 'off';
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Enable = 'off';
            app.xmaxEditFieldLabel.Visible = 'off';
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.Editable = 'off';
            app.xmaxEditField.Enable = 'off';
            app.xmaxEditField.Visible = 'off';
            app.yminEditFieldLabel = uilabel(app.UIFigure);
            app.yminEditFieldLabel.Enable = 'off';
            app.yminEditFieldLabel.Visible = 'off';
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.Editable = 'off';
            app.yminEditField.Enable = 'off';
            app.yminEditField.Visible = 'off';
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Enable = 'off';
            app.ymaxEditFieldLabel.Visible = 'off';
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.Editable = 'off';
            app.ymaxEditField.Enable = 'off';
            app.ymaxEditField.Visible = 'off';
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'text');
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.RecalculateButton = uibutton(app.UIFigure, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateButtonPushed, true);
            app.RecalculateButton.Enable = 'off';
            app.RecalculateButton.Visible = 'off';
            app.RecalculateButton.Text = 'Recalculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end